classdef EC3CMGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EPCBackboneModelUIFigure  matlab.ui.Figure
        Compute                   matlab.ui.control.Button
        TabGroup                  matlab.ui.container.TabGroup
        MRBackboneTab             matlab.ui.container.Tab
        Panel_4                   matlab.ui.container.Panel
        Hyperlink                 matlab.ui.control.Hyperlink
        savefigButton             matlab.ui.control.Button
        Image_3                   matlab.ui.control.Image
        NormalizeY                matlab.ui.control.CheckBox
        Plot                      matlab.ui.control.UIAxes
        DeformationmodeclassificationTab  matlab.ui.container.Tab
        Panel_5                   matlab.ui.container.Panel
        Hyperlink_2               matlab.ui.control.Hyperlink
        LegendButton              matlab.ui.control.Button
        ClassificationText        matlab.ui.control.TextArea
        Image_4                   matlab.ui.control.Image
        Panel_3                   matlab.ui.container.Panel
        Plate_18                  matlab.ui.control.Label
        bep                       matlab.ui.control.NumericEditField
        PlotBolt                  matlab.ui.control.Button
        Plate_17                  matlab.ui.control.Label
        LayoutLabel               matlab.ui.control.Label
        menuLayout                matlab.ui.control.DropDown
        LoadtypeLabel             matlab.ui.control.Label
        menuLoad                  matlab.ui.control.DropDown
        Image_2                   matlab.ui.control.Image
        Plate_16                  matlab.ui.control.Label
        pt                        matlab.ui.control.NumericEditField
        Plate_15                  matlab.ui.control.Label
        hc                        matlab.ui.control.NumericEditField
        bcf                       matlab.ui.control.NumericEditField
        tcw                       matlab.ui.control.NumericEditField
        tcf                       matlab.ui.control.NumericEditField
        hb                        matlab.ui.control.NumericEditField
        bbf                       matlab.ui.control.NumericEditField
        tbw                       matlab.ui.control.NumericEditField
        tbf                       matlab.ui.control.NumericEditField
        tep                       matlab.ui.control.NumericEditField
        fyP                       matlab.ui.control.NumericEditField
        fub                       matlab.ui.control.NumericEditField
        TS                        matlab.ui.control.CheckBox
        Plate_14                  matlab.ui.control.Label
        Plate_13                  matlab.ui.control.Label
        Plate_12                  matlab.ui.control.Label
        Plate_11                  matlab.ui.control.Label
        fuP                       matlab.ui.control.NumericEditField
        ColumnLabel               matlab.ui.control.Label
        menuColumn                matlab.ui.control.DropDown
        BeamLabel                 matlab.ui.control.Label
        menuBeam                  matlab.ui.control.DropDown
        Plate_8                   matlab.ui.control.Label
        Plate_7                   matlab.ui.control.Label
        Plate_6                   matlab.ui.control.Label
        Plate_5                   matlab.ui.control.Label
        fyB                       matlab.ui.control.NumericEditField
        fyC                       matlab.ui.control.NumericEditField
        fuC                       matlab.ui.control.NumericEditField
        menuBolt                  matlab.ui.control.DropDown
        Plate_4                   matlab.ui.control.Label
        fyb                       matlab.ui.control.NumericEditField
        Plate_2                   matlab.ui.control.Label
        Plate                     matlab.ui.control.Label
        g                         matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        SecDB
        BoltDB
        MainDir
        SelectedBoltIdx = []   % optional cache of selected bolt index
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Ensure engine folder is on path
            addpath(fullfile(fileparts(mfilename('fullpath')),'engine'));

            clc;

            % Root folder (where the .mlapp sits)
            app.MainDir = fileparts(mfilename('fullpath'));

            % Load databases into app properties (no globals)
            secPath  = fullfile(app.MainDir, 'Database_Sections.mat');
            boltPath = fullfile(app.MainDir, 'Database_Bolts.mat');
            S = load(secPath);   app.SecDB  = S.SecDB;
            B = load(boltPath);  app.BoltDB = B.BoltDB;

            % Axes
            app.Plot.XTickMode = 'auto';
            app.Plot.YTickMode = 'auto';

            % Populate dropdowns
            keep = contains(app.SecDB.AltName,'HB') | contains(app.SecDB.AltName,'HE') | ...
                contains(app.SecDB.AltName,'IPE')| contains(app.SecDB.AltName,'W')  | ...
                contains(app.SecDB.AltName,'UC') | contains(app.SecDB.AltName,'UB');

            FilteredSecDB = app.SecDB(keep,:);
            z = ['Built-up'; string(FilteredSecDB.AltName)];

            app.menuColumn.Items = z;
            app.menuBeam.Items   = z;
            app.menuBolt.Items   = string(app.BoltDB.Name);

            % Defaults
            app.menuColumn.Value = 'HE 200 B';
            app.menuBeam.Value   = 'IPE 300';
            app.menuBolt.Value   = 'M16';

            % Populate dimension fields
            menuColumnValueChanged(app, []);
            menuBeamValueChanged(app,   []);
            menuBoltValueChanged(app,   []);

        end

        % Value changed function: menuColumn
        function menuColumnValueChanged(app, event)
            % Uses SecDB loaded in startupFcn (global or app property—your current code uses global)
            if app.menuColumn.Value~="Built-up" && app.menuColumn.Value~="None" && app.menuColumn.Value~="Rigid"
                Column = app.menuColumn.Value;
                Indx   = find(strcmp(app.SecDB.AltName, Column), 1, 'first');
                if ~isempty(Indx)
                    app.hc.Value  = app.SecDB.d(Indx);
                    app.bcf.Value = app.SecDB.bf(Indx);
                    app.tcw.Value = app.SecDB.tw(Indx);
                    app.tcf.Value = app.SecDB.tf(Indx);
                end
            end
        end

        % Value changed function: menuBeam
        function menuBeamValueChanged(app, event)
            if app.menuBeam.Value~="Built-up"
                Beam = app.menuBeam.Value;
                Indx = find(strcmp(app.SecDB.AltName, Beam), 1, 'first');
                if ~isempty(Indx)
                    app.hb.Value  = app.SecDB.d(Indx);
                    app.bbf.Value = app.SecDB.bf(Indx);
                    app.tbw.Value = app.SecDB.tw(Indx);
                    app.tbf.Value = app.SecDB.tf(Indx);
                end
            end
        end

        % Value changed function: menuBolt
        function menuBoltValueChanged(app, event)
            if app.menuBolt.Value~="Built-up"
                Bolt = app.menuBolt.Value;
                idx  = find(strcmp(app.BoltDB.Name, Bolt), 1, 'first');  % <-- fixed
                app.SelectedBoltIdx = idx;  % optional: keep for later use
                % (Nothing else to set right now, but you can auto-fill bolt fields later.)
            end
        end

        % Button pushed function: Compute
        function ComputeButtonPushed(app, event)
            global SecDB BoltDB p1 p2 p3 p4 p5 p6 MainDir
            clc;

            %             try
            ConnectionType      = 'Extended';
            ColSec     = string(app.menuColumn.Value);
            BeamSec    = string(app.menuBeam.Value);
            Bolt       = string(app.menuBolt.Value);

            if app.TS.Value==0; StiffenerC = "No"; end


            hc  = app.hc.Value;
            bcf = app.bcf.Value;
            tcw = app.tcw.Value;
            tcf = app.tcf.Value;

            hb  = app.hb.Value;
            bbf = app.bbf.Value;
            tbw = app.tbw.Value;
            tbf = app.tbf.Value;
            IxB =  tbw*(hb-2*tbf)^3/12 + 2*bbf*tbf^3/12 + 2*bbf*tbf * ((hb-tbf)/2)^2;
            ZxB =  bbf*hb^2/4-(bbf-tbw)*(hb-2*tbf)^2/4;

            g = app.g.Value;
            bep = app.bep.Value;
            tep = app.tep.Value;
            pt = app.pt.Value;


            StiffC = app.TS.Value;
            Load   = app.menuLoad.Value;
            Layout = app.menuLayout.Value;

            Indx = find(strcmp(app.BoltDB.Name, Bolt), 1, 'first');
            d_b  = app.BoltDB.db(Indx);
            As   = app.BoltDB.As(Indx);


            fyB = app.fyB.Value;
            fyC = app.fyC.Value;
            fuC = app.fuC.Value;
            fyP = app.fyP.Value;
            fuP = app.fuP.Value;
            fyb = app.fyb.Value;
            fub = app.fub.Value;

            if StiffC==1; StiffC="Yes"; else; StiffC="No"; end

            if app.NormalizeY.Value==1
                NormY=fyB*ZxB/10^6;
                app.Plot.YLabel.String='\itM\rm / \itM\rm_{p,beam}';
            else
                NormY=1;
                app.Plot.YLabel.String='\itM\rm [kN.m]';
            end

            %% Check input
            if g>=bcf-2*d_b
                errordlg('Bolt gauge length (g) shall be smaller than the column flange width. Please check!','Error');
                return;
            end

            if bep>bcf || bep<bbf
                errordlg('Endplate width (bep) shall be larger than the beam flange width and less than column flange width. Please check!','Error');
                return;
            end

            %% Check validity range
            if tcf/tep > 1.8 || tcf/tep < 0.5
                warndlg('The connection topology is outside the model validity range (tcf/tep). Estimated response might not be accuracte!','Warning: Outside validity range');
            end

            if hb/hc > 2.1 || hb/hc < 0.90
                warndlg('The connection topology is outside the model validity range (hb/hc). Estimated response might not be accuracte!','Warning: Outside validity range');
            end

            if d_b/tep > 2 || d_b/tep < 0.55
                warndlg('The connection topology is outside the model validity range (db/tep). Estimated response might not be accuracte!','Warning: Outside validity range');
            end

            if pt/tep > 13.5 || pt/tep < 3
                warndlg('The connection topology is outside the model validity range (pt/tep). Estimated response might not be accuracte!','Warning: Outside validity range');
            end

            %% MVLR models
            [Ke, My, Mye, Mc, thetaC, R_MVLR, M_MVLR] = get_Model_EEPC_MVLR(tep, tcf, pt, d_b, hb, g, hc, tcw, fyP, fuP, fub, fyC, fuC, StiffC, Load, Layout);

            %% ANN models
            % [R_ANN_MISO,M_ANN_MISO,Mye_MISO,Ke_MISO,Ks_MISO,thetaC_MISO] = get_Model_EEPC_ANN_MISO(pt,tep,tcf,tcw,hb,hc,d_b,fyP,fyC,fuC,fuP,fyb,fub,StiffC,Load,g,Layout,tbf,bbf);

            %% ANN_all model
            % [R_ANN_MIMO,M_ANN_MIMO,Ke_MIMO Mye_MIMO Ks_MIMO theta_MIMO] = get_Model_EEPC_ANN_MIMO(pt,tep,tcf,tcw,hb,hc,d_b,fyP,fyC,fuC,fuP,fyb,fub,StiffC,Load,g,Layout,tbf,bbf);

            cla(app.Plot);
            delete(p1); delete(p2); delete(p3); delete(p4); delete(p5); delete(p6);
            hold(app.Plot,"on");
            p1=plot(app.Plot,R_MVLR*100, M_MVLR/NormY,'-ok','linewidth',1,'DisplayName','MVLR');
            %p2=plot(app.Plot,R(2:end-1)*100, M(2:end-1)/NormY,'ok');
            out{1}=['\itK\rm_{e } = ',num2str(round(Ke)), ' kN.m/rad'];
            out{2}=['\itM\rm_{y } = ',num2str(round(My)), ' kN.m'];
            out{3}=['\itM\rm_{ye} = ',num2str(round(Mye)),' kN.m'];
            out{4}=['\itM\rm_{c } = ',num2str(round(Mc)) ,' kN.m'];
            out{5}=['\it\theta\rm_{c }  = ',num2str(round(thetaC*10000)/100) ,' % rads'];
            p4=text(app.Plot,1.05*R_MVLR(end-1)*100, 0.45*(Mc/NormY),out,'fontname','times','fontsize',14);

            % p5=plot(app.Plot,R_ANN_MISO*100, M_ANN_MISO/NormY,'--ob','linewidth',1,'DisplayName','ANN');
            % p6=plot(app.Plot,R_ANN_MIMO*100, M_ANN_MIMO/NormY,'--or','linewidth',1,'DisplayName','ANN-MIMO');

            app.Plot.XLim=[0 1.2*R_MVLR(end)*100];
            app.Plot.YLim=[0 1.2*(Mc/NormY)];

            % ===== Orchestrate the OpenSees run (GUI drives the pipeline) =====
            try
                % 1) gather inputs from GUI (including DB-derived fields already placed in edit boxes)
                P      = pack_inputs_from_gui(app);

                % 2) derived geometry + reference line
                P      = derive_geometry(P);        % adds hcf etc.

                % 3) choose connector springs (placeholder -> later wire EPB/CFB logic & BoltDB)
                P      = select_connectors(P);

                % 4) write full Tcl by sections
                paths  = struct('baseDir', fullfile(tempdir,'EEPC_OpenSees'));
                tcl    = emit_tcl_bundle(P, paths);

                % 5) run OpenSees
                opensees_exec(tcl);

                % 6) read results (summary + histories)
                R      = collect_results(fullfile(paths.baseDir,'results'));

                % 7) moment–rotation series & plot on app axes
                [theta_hist, M_hist_Nmm] = mr_series(R);
                x = theta_hist * 100;               % [% rad]
                y = M_hist_Nmm / 1e6;               % [kN·m]
                if app.NormalizeY.Value==1, y = y/NormY; end

                hold(app.Plot,"on");
                plot(app.Plot, x, y, '--r','LineWidth',1.6,'DisplayName','OpenSees');
                legend(app.Plot,'show','Location','best');

                title(app.Plot, sprintf('\\theta_f=%.3g rad | M_i(final)=%.3g kN·m | V@node30=%.3g kN', ...
                    R.theta, R.Mi/1e6, R.Fy/1e3));

                app.ClassificationText.Value = [app.ClassificationText.Value; {
                    '--- OpenSees joint (no-offsets) ---'
                    sprintf('theta (node 30 Rz) : %.6g rad', R.theta)
                    sprintf('M_i (elem 900)     : %.6g kN·m', R.Mi/1e6)
                    sprintf('V@node30           : %.6g kN',   R.Fy/1e3)
                    ' '
                    }];
            catch ME
                warning('OpenSees pipeline error: %s', ME.message);
            end


            if pt/tep < 9
                deltaWeldFailure = -35+8.5*(pt/tep);
                rotWeldFailure= deltaWeldFailure/(hb-tbf);
                if rotWeldFailure < thetaC
                    warndlg(['Note that this connection may be susceptible to weld failure at rotation less than ', num2str(round(rotWeldFailure*100,1)), '% (prior to bolt failure)'],'Warning: Possibility of early weld failure','replace');
                end
            end


            if Load == "Sym"
                Load = 1;
            else
                Load = 0;
            end
            StiffC = app.TS.Value;

            [predictedMode, modes_and_probability] = get_EEPC_mode_class(StiffC, Load, pt, tep, tcf, bep, hb, bbf, tbf, hc, tcw, fyP, fyC);

            %% Output

            str{1} =['The expected deformation mode is ', char(predictedMode)];
            str{2} =['---------------------------------------------------------'];
            str{3} =[''];
            str{4} =['Mode     Probability [%]'];
            str{5} =['-------------------------------'];
            str{6} =['BB                 ', char(modes_and_probability(1,2))];
            str{7} =['Balanced        ', char(modes_and_probability(2,2))];
            str{8} =['CFB               ', char(modes_and_probability(3,2))];
            str{9} =['CWS              ', char(modes_and_probability(4,2))]
            str{10}=['EPB                ', char(modes_and_probability(5,2))]
            str{11}=['EPB+CFB      ', char(modes_and_probability(6,2))]

            app.ClassificationText.Value=str;


            %             catch ME
            %                 cd (MainDir);
            %                 write_Error(ME);
            %                 error('An error has occured! Please check the generated error file in the main directory and forward it to the author.');
            %             end
        end

        % Value changed function: NormalizeY
        function NormalizeYValueChanged(app, event)
            ComputeButtonPushed(app)
        end

        % Value changed function: menuLoad
        function menuLoadValueChanged(app, event)
            if app.menuModel.Value == "Extended"
                ComputeButtonPushed(app)
            end
        end

        % Button pushed function: savefigButton
        function savefigButtonPushed(app, event)
            SavePath= uigetdir;

            Column = app.menuColumn.Value;
            Beam   = app.menuBeam.Value;
            tep    = app.tep.Value;

            FigFilename=['\',Column,'_',Beam,'_tep',tep,'_Backbone_Curve.fig'];

            z=[SavePath, FigFilename];

            fig = figure('position',[100 100 400 350],'color','white');
            h = copyUIAxes(app.Plot, fig);
            savefig(fig, z);
            delete(fig);
        end

        % Button pushed function: PlotBolt
        function PlotBoltButtonPushed(app, event)
            plot_EEPC(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.EPCBackboneModelUIFigure = uifigure('Visible', 'off');
            app.EPCBackboneModelUIFigure.Name = 'EPC Backbone Model';
            app.EPCBackboneModelUIFigure.Icon = fullfile(pathToMLAPP, 'FEP.jpg');
            app.Panel_3 = uipanel(app.EPCBackboneModelUIFigure);
            app.g = uieditfield(app.Panel_3, 'numeric');
            app.g.Limits = [0 Inf];
            app.g.Tooltip = {'End-plate thickness'};
            app.g.Value = 100;
            app.Plate = uilabel(app.Panel_3);
            app.Plate.Text = {'|'; '|'; '|'; '|'; '|'; '|'; '|'};
            app.Plate_2 = uilabel(app.Panel_3);
            app.Plate_2.Text = ' Backbone Model & Deformation Classification for Extended Endplate Connections';
            app.fyb = uieditfield(app.Panel_3, 'numeric');
            app.fyb.Limits = [0 Inf];
            app.fyb.Tooltip = {'Yiekd stress of the bolt [MPa]'};
            app.fyb.Value = 640;
            app.Plate_4 = uilabel(app.Panel_3);
            app.Plate_4.Text = 'Size';
            app.menuBolt = uidropdown(app.Panel_3);
            app.menuBolt.ValueChangedFcn = createCallbackFcn(app, @menuBoltValueChanged, true);
            app.fuC = uieditfield(app.Panel_3, 'numeric');
            app.fuC.Limits = [0 Inf];
            app.fuC.Tooltip = {'Ultimate stress of the bolt [MPa]'};
            app.fuC.Value = 470;
            app.fyC = uieditfield(app.Panel_3, 'numeric');
            app.fyC.Limits = [0 Inf];
            app.fyC.Tooltip = {'Yield stress of the column [MPa]'};
            app.fyC.Value = 355;
            app.fyB = uieditfield(app.Panel_3, 'numeric');
            app.fyB.Limits = [0 Inf];
            app.fyB.Tooltip = {'Yield stress of the column [MPa]'};
            app.fyB.Value = 355;
            app.Plate_5 = uilabel(app.Panel_3);
            app.Plate_5.Text = 'Bolts:';
            app.Plate_6 = uilabel(app.Panel_3);
            app.Plate_6.Text = 'fy';
            app.Plate_7 = uilabel(app.Panel_3);
            app.Plate_7.Text = 'fu';
            app.Plate_8 = uilabel(app.Panel_3);
            app.Plate_8.Text = 'End-Plate:';
            app.menuBeam = uidropdown(app.Panel_3);
            app.menuBeam.ValueChangedFcn = createCallbackFcn(app, @menuBeamValueChanged, true);
            app.BeamLabel = uilabel(app.Panel_3);
            app.BeamLabel.Text = 'Beam:';
            app.menuColumn = uidropdown(app.Panel_3);
            app.menuColumn.ValueChangedFcn = createCallbackFcn(app, @menuColumnValueChanged, true);
            app.ColumnLabel = uilabel(app.Panel_3);
            app.ColumnLabel.Text = 'Column:';
            app.fuP = uieditfield(app.Panel_3, 'numeric');
            app.fuP.Limits = [0 Inf];
            app.fuP.Tooltip = {'Ultimate stress of the bolt [MPa]'};
            app.fuP.Value = 470;
            app.Plate_11 = uilabel(app.Panel_3);
            app.Plate_11.Text = 'tf';
            app.Plate_12 = uilabel(app.Panel_3);
            app.Plate_12.Text = 'tw';
            app.Plate_13 = uilabel(app.Panel_3);
            app.Plate_13.Text = 'b';
            app.Plate_14 = uilabel(app.Panel_3);
            app.Plate_14.Text = 'h';
            app.TS = uicheckbox(app.Panel_3);
            app.TS.Tooltip = {'Column flange stiffeners'};
            app.TS.Text = 'Column stiffeners';
            app.fub = uieditfield(app.Panel_3, 'numeric');
            app.fub.Limits = [640 Inf];
            app.fub.Tooltip = {'Ultimate stress of the bolt [MPa]'};
            app.fub.Value = 800;
            app.fyP = uieditfield(app.Panel_3, 'numeric');
            app.fyP.Limits = [0 Inf];
            app.fyP.Tooltip = {'Yield stress of the column [MPa]'};
            app.fyP.Value = 355;
            app.tep = uieditfield(app.Panel_3, 'numeric');
            app.tep.Limits = [0 Inf];
            app.tep.Tooltip = {'End-plate thickness'};
            app.tep.Value = 12;
            app.tbf = uieditfield(app.Panel_3, 'numeric');
            app.tbf.Limits = [0 Inf];
            app.tbw = uieditfield(app.Panel_3, 'numeric');
            app.tbw.Limits = [0 Inf];
            app.bbf = uieditfield(app.Panel_3, 'numeric');
            app.bbf.Limits = [0 Inf];
            app.hb = uieditfield(app.Panel_3, 'numeric');
            app.hb.Limits = [0 Inf];
            app.tcf = uieditfield(app.Panel_3, 'numeric');
            app.tcw = uieditfield(app.Panel_3, 'numeric');
            app.bcf = uieditfield(app.Panel_3, 'numeric');
            app.hc = uieditfield(app.Panel_3, 'numeric');
            app.Plate_15 = uilabel(app.Panel_3);
            app.Plate_15.Text = 'pt';
            app.pt = uieditfield(app.Panel_3, 'numeric');
            app.pt.Limits = [0 Inf];
            app.pt.Tooltip = {'Distance from plate edge to first bolt row in tension'};
            app.pt.Value = 85;
            app.Plate_16 = uilabel(app.Panel_3);
            app.Plate_16.Text = 'g';
            app.Image_2 = uiimage(app.Panel_3);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'Southampton_logo.png');
            app.menuLoad = uidropdown(app.Panel_3);
            app.menuLoad.Items = {'Asymmetric', 'Symmetric'};
            app.menuLoad.ItemsData = {'Asym', 'Sym'};
            app.menuLoad.ValueChangedFcn = createCallbackFcn(app, @menuLoadValueChanged, true);
            app.menuLoad.Value = 'Asym';
            app.LoadtypeLabel = uilabel(app.Panel_3);
            app.LoadtypeLabel.Text = 'Load type:';
            app.menuLayout = uidropdown(app.Panel_3);
            app.menuLayout.Items = {'8-bolt', '10-bolt'};
            app.menuLayout.Value = '8-bolt';
            app.LayoutLabel = uilabel(app.Panel_3);
            app.LayoutLabel.Text = 'Layout:';
            app.Plate_17 = uilabel(app.Panel_3);
            app.Plate_17.Text = 'v1.2510';
            app.PlotBolt = uibutton(app.Panel_3, 'push');
            app.PlotBolt.ButtonPushedFcn = createCallbackFcn(app, @PlotBoltButtonPushed, true);
            app.PlotBolt.Tooltip = {'show connection layout'};
            app.PlotBolt.Text = 'Plot connection';
            app.bep = uieditfield(app.Panel_3, 'numeric');
            app.bep.Limits = [0 Inf];
            app.bep.Tooltip = {'End-plate thickness'};
            app.bep.Value = 150;
            app.Plate_18 = uilabel(app.Panel_3);
            app.Plate_18.Text = 'bep';
            app.TabGroup = uitabgroup(app.EPCBackboneModelUIFigure);
            app.MRBackboneTab = uitab(app.TabGroup);
            app.MRBackboneTab.Title = 'M-R Backbone';
            app.Panel_4 = uipanel(app.MRBackboneTab);
            app.Plot = uiaxes(app.Panel_4);
            xlabel(app.Plot, '\it\theta\rm [% rad]')
            ylabel(app.Plot, '\itM\rm [kN.m]')
            app.NormalizeY = uicheckbox(app.Panel_4);
            app.NormalizeY.ValueChangedFcn = createCallbackFcn(app, @NormalizeYValueChanged, true);
            app.NormalizeY.Tooltip = {'normalize y-axis by the beam''s plastic moment (based on measured fy)'};
            app.NormalizeY.Text = 'Normalize by Mp,b';
            app.Image_3 = uiimage(app.Panel_4);
            app.Image_3.ImageSource = fullfile(pathToMLAPP, 'Connection-Parameters-EEP-MVLR.jpg');
            app.savefigButton = uibutton(app.Panel_4, 'push');
            app.savefigButton.ButtonPushedFcn = createCallbackFcn(app, @savefigButtonPushed, true);
            app.savefigButton.Tooltip = {'save figure'};
            app.savefigButton.Text = '';
            app.savefigButton.Icon = fullfile(pathToMLAPP, 'save.jpg');
            app.Hyperlink = uihyperlink(app.Panel_4);
            app.Hyperlink.URL = 'https://www.sciencedirect.com/science/article/pii/S0141029625018565?via%3Dihub';
            app.Hyperlink.Text = 'reference';
            app.DeformationmodeclassificationTab = uitab(app.TabGroup);
            app.DeformationmodeclassificationTab.Title = 'Deformation mode classification';
            app.Panel_5 = uipanel(app.DeformationmodeclassificationTab);
            app.Image_4 = uiimage(app.Panel_5);
            app.Image_4.ImageSource = fullfile(pathToMLAPP, 'Connection-Parameters-EEP-Classification.jpg');
            app.ClassificationText = uitextarea(app.Panel_5);
            app.ClassificationText.Value = {''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.LegendButton = uibutton(app.Panel_5, 'push');
            app.LegendButton.Tooltip = {'Legend'};
            app.LegendButton.Text = '?';
            app.Hyperlink_2 = uihyperlink(app.Panel_5);
            app.Hyperlink_2.URL = 'https://link.springer.com/chapter/10.1007/978-3-031-62884-9_47';
            app.Hyperlink_2.Text = 'reference';
            app.Compute = uibutton(app.EPCBackboneModelUIFigure, 'push');
            app.Compute.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.Compute.Text = 'Compute';
            % Show the figure after all components are created
            app.EPCBackboneModelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EC3CMGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EPCBackboneModelUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EPCBackboneModelUIFigure)
        end
    end
end